classdef protein_intake < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        breakfast_items               matlab.ui.control.Table
        linner_items                  matlab.ui.control.Table
        createReport                  matlab.ui.control.Button
        EnterWeightkgsEditFieldLabel  matlab.ui.control.Label
        weight                        matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        intakeGraph                   matlab.ui.control.UIAxes
        refresh                       matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Read protein values table
    properties (Access = private)
        breakfast = readtable('breakfast.xlsx');
        linner = readtable('lunch_dinner.xlsx')
        total_intake = 0;
        
        % values for strength and endurance athletes
        % rec = [0 1.0; 0.8 1.2; 1.4 2; 1.0 1.8]; 
        
        rec = [0.1 0.8 1.2 1.4]; % regular population
    end
    
    methods (Access = private)
        function plotdata(app)         
%             x = categorical({'Today','Regular','Strength Athletes','Endurance Athletes'});
%             x = reordercats(x,{'Today','Regular','Strength Athletes','Endurance Athletes'});
            
            x = categorical({'Your Intake','Minimum','Maximum','Active Sport Lifestyle'});
            x = reordercats(x,{'Your Intake','Minimum','Maximum','Active Sport Lifestyle'});
            y = app.rec;
            
            tx = num2cell(app.rec);
            graph = bar(app.intakeGraph,x,y);
            graph.FaceColor = 'flat';
            text(app.intakeGraph, x, y, tx, 'VerticalAlignment','bottom') 
            graph.CData(2,:) = [.2 .6 .5];
            graph.CData(3,:) = [0.8500 0.3250 0.0980];
            graph.CData(4,:) = [0.6350 0.0780 0.1840];
            
            app.rec = [0.1 0.8 1.2 1.4];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % subset of vars
            vars = {'Name','Yes','ServingType','Qty'};
            
            % populate breakfast items table
            temp = app.breakfast;
            temp = temp(:,vars);
            app.breakfast_items.Data = temp;
            % populate linner items table
            temp = app.linner;
            temp = temp(:,vars);
            app.linner_items.Data = temp;   
            
            % table display properties
            text_default = uistyle('FontColor',[0 0.2 0.33],'FontWeight','bold');
            addStyle(app.breakfast_items,text_default, 'column', 1);
            addStyle(app.linner_items,text_default, 'column', 1);
            %row_default = uistyle('BackgroundColor',[0.97 0.97 0.97]);      
        end

        % Button pushed function: createReport
        function createIntakeReport(app, event)
            b = app.breakfast;
            ld = app.linner;
            
            b_user = app.breakfast_items.Data;
            ld_user = app.linner_items.Data;
            
            b_sum = app.total_intake;
            ld_sum = app.total_intake;
            
            for i = 1:size(b_user,1)
                b_sum = b_sum + (b.pp_serving(i) * b_user{i,4});
            end
            
            for i = 1:size(ld_user,1)
                ld_sum = ld_sum + (ld.pp_serving(i) * ld_user{i,4});
            end
            
            total = b_sum + ld_sum;
            app.rec(1,1) = total;
            
            for i=2:size(app.rec,2)
                app.rec(1,i) = app.rec(1,i) * app.weight.Value;
            end
            
%             for i=2:size(app.rec,1)
%                 for j=1:size(app.rec,2)
%                     app.rec(i,j) = app.rec(i,j) * app.weight.Value;
%                 end
%             end
            
            plotdata(app);
        end

        % Button pushed function: refresh
        function refreshGraph(app, event)
            app.intakeGraph.cla;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {744, 744};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {481, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Protein Intake Calculator';
            app.breakfast_items = uitable(app.LeftPanel);
            app.breakfast_items.ColumnName = {'Item'; 'Yes'; 'Serving Size'; 'Amount'};
            app.breakfast_items.RowName = {};
            app.breakfast_items.ColumnEditable = [false true false true];
            app.linner_items = uitable(app.LeftPanel);
            app.linner_items.ColumnName = {'Item'; 'Yes'; 'Serving Size'; 'Amount'};
            app.linner_items.RowName = {};
            app.linner_items.ColumnEditable = [false true false true];
            app.createReport = uibutton(app.LeftPanel, 'push');
            app.createReport.ButtonPushedFcn = createCallbackFcn(app, @createIntakeReport, true);
            app.createReport.Text = 'Create Report';
            app.EnterWeightkgsEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterWeightkgsEditFieldLabel.Text = 'Enter Weight (kgs)';
            app.weight = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.intakeGraph = uiaxes(app.RightPanel);
            title(app.intakeGraph, {'Protein Intake'; ''})
            xlabel(app.intakeGraph, '')
            ylabel(app.intakeGraph, 'Protein (g)')
            app.intakeGraph.YLim = [0 140];
            app.intakeGraph.XTick = [];
            app.intakeGraph.XTickLabel = '';
            app.intakeGraph.YTick = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140];
            app.intakeGraph.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'; '110'; '120'; '130'; '140'};
            app.refresh = uibutton(app.RightPanel, 'push');
            app.refresh.ButtonPushedFcn = createCallbackFcn(app, @refreshGraph, true);
            app.refresh.Text = 'Refresh';
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.Value = {'Recommended daily intake: 0.8-1.2g / kg of bodyweight'; 'Applies for sedentary and moderately active lifestyle (normal)'; ''; 'For those strength training and/or training for a sport or endurance event increase the intake to 1.4g / kg of bodyweight'; ''; 'Further increases (1.4 - 2.0 g / kg) are only recommended for professional athletes or when following a rigorous training program'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = protein_intake

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end